classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ProcessTab                matlab.ui.container.Tab
        EpochSpinner_2Label       matlab.ui.control.Label
        EpochSpinner_2            matlab.ui.control.Spinner
        BatchSpinner_2Label       matlab.ui.control.Label
        BatchSpinner_2            matlab.ui.control.Spinner
        SelectFiletoUploadButton  matlab.ui.control.Button
        RunInitializationButton   matlab.ui.control.Button
        RunAnalyzationButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFiletoUploadButton
        function SelectFiletoUploadButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.*', 'Please select a file to import');
            
            img=dicomread([filepath filename]);
            img=squeeze(img);
            info=dicominfo([filepath filename]);

            figure('name',info.PatientID),montage(img,'DisplayRange',[230 750],'size',[5 nan]);

        %% determine the laterality of image based on voxel locations
            MIPimg=mean(img,3);  % c-view: summation
            %MIPimg=max(img,[],3);  % c-view
            [row,col]=find(MIPimg~=0);
            centerC=mean(col);
                
                if centerC<round(col/2)
                    laterality_check=1;  % 0 for left, 1 for right
                    ImgLaterality='R';
                    ImgLaterality_r='L';
                    %figure('name',[info.PatientID,' Mean image']), imshow(MIPimg,[230 750]), text(50,80,[ImgLaterality info.ViewPosition],'fontsize',30,'fontweight','bold','color','w')
                else
                    laterality_check=0;  % 0 for left, 1 for right
                    ImgLaterality='L';
                    ImgLaterality_r='R';
                    %figure('name',[info.PatientID,' Mean image']), imshow(MIPimg,[230 750]), text(size(MIPimg,2)-50,80,[ImgLaterality info.ViewPosition],'HorizontalAlignment','right','fontsize',30,'fontweight','bold','color','w')
                end
    
        %% import bounding box information
            [~,~,targetlist]=xlsread('BCS-DBT boxes-train-v2.xlsx');
            
            imglabel=[];
            checkind=find(ismember(targetlist(:,1),info.PatientID));  % compare PatientID
            if ~isempty(checkind)
                imglabel=[ImgLaterality info.ViewPosition];
                checkind2=find(ismember(targetlist(checkind,3),lower(imglabel)));  % compare View
                if isempty(checkind2)  % try on another side
                    imglabel=[ImgLaterality_r info.ViewPosition];
                    laterality_check=abs(1-laterality_check); % flip left and right
                    checkind2=find(ismember(targetlist(checkind,3),lower(imglabel)));  % compare View
                    if ~isempty(checkind2)
                        fprintf(['Bounding box is found in another side (', imglabel, '). \n'])
                    else
                        fprintf('No matched bounding box is found in the list. \n')
                        return
                    end
                end


        %% display bounding box
        for slice=1:length(checkind2)
            targetslice=targetlist{checkind(checkind2(slice)),5};
            lesionClass=targetlist{checkind(checkind2(slice)),10};
            tmp=targetlist(checkind(checkind2(slice)),6:9);
            bBox=[tmp{1},tmp{2},tmp{3},tmp{4}];
        %% Save current figure
            filename = [info.PatientID '_' ImgLaterality info.ViewPosition, '_S' num2str(targetslice) '_L' num2str(slice) '_' lesionClass];
            filename = strrep(filename , '.dcm', '');
            currentFolder = '/Users/irischen/Desktop/app/'
            desiredFolder = strrep(upper([currentFolder '/']), '//', '/Input/')
            if ~exist(desiredFolder, 'dir')
            %   mkdir(desiredFolder);
            end
            saveas(gcf,filename, 'fig')
            save(filename)
            
            img_lesion = img(:,:,targetslice);
            img_lesion_mask = poly2mask([bBox(1) bBox(1) bBox(1)+bBox(3) bBox(1)+bBox(3) bBox(1)],[bBox(2) bBox(2)+bBox(4) bBox(2)+bBox(4) bBox(2) bBox(2)], size(img_lesion,1),size(img_lesion,2));
            save(filename, 'info' , 'ImgLaterality' , 'targetslice' , 'lesionClass' , 'img_lesion' , 'img_lesion_mask')
        end
        
    else
       fprintf('No matched bounding box is found in the list. \n') 
    end
    targetslice=targetlist{checkind(checkind2),5};
    lesionClass=targetlist{checkind(checkind2),10};
    %save([info.PatientID '_' ImgLaterality info.ViewPosition],'img','targetslice' , 'lesionClass' , 'info')
    
        end

        % Button pushed function: RunInitializationButton
        function RunInitializationButtonPushed(app, event)
        path='/Users/irischen/Desktop/app/Input/';
        save_path='/Users/irischen/Desktop/app/Patch/';
        file=dir(path); file(1:3)=[];
        IMGsize=908; %908
        stride=50;
        for i=1:length(file)
            h=1;
            tmp= load([path file(i).name]);
            file_name=file(i).name;
            img=double(tmp.img_lesion);
            img=(img-min(img(:)))/(max(img(:))-min(img(:)));
            mask=tmp.img_lesion_mask;
            j=0;
            k=0;
            Startrow=1;
            Startcol=1;
            IMGdata={};
            mask_row=[];
            mask_col=[];
            boundingbox={};
            center=[];
            if sum(mask(:))<=50000
                [mask_row,mask_col]=find(mask>0);
                boundingbox{1}=min(mask_col);
                boundingbox{2}=min(mask_row);
                boundingbox{3}=max(mask_col)-min(mask_col)+1;
                boundingbox{4}=max(mask_row)-min(mask_row)+1;
                center(1,1)=round(boundingbox{1}+boundingbox{3}/2);
                center(1,2)=round(boundingbox{2}+boundingbox{4}/2);
                l=center(1,1)-round((IMGsize)/2);
                r=l+907;
                u=center(1,2)-round((IMGsize)/2);
                d=u+907;
                if l<=0
                    l=1;
                    r=908;
                end
                if r>size(img,2)
                    r=size(img,2);
                    l=size(img,2)-907;
                end
                if u<=0
                    u=1;
                    d=908;
                end
                if d>size(img,1)
                    d=size(img,1);
                    u=size(img,1)-907;
                end
                img_Patch=img(u:d,l:r);
                mask_Patch=mask(u:d,l:r);
        
                [mask_row,mask_col]=find(mask_Patch>0);
                boundingbox{1}=min(mask_col);
                boundingbox{2}=min(mask_row);
                boundingbox{3}=max(mask_col)-min(mask_col)+1;
                boundingbox{4}=max(mask_row)-min(mask_row)+1;
                if size(find(img_Patch>0),1)>IMGsize*IMGsize*0.6
                     save([save_path file_name(1:end-4) '_' num2str(h) '.mat'],'img_Patch','boundingbox');
        
                   I = insertObjectAnnotation(img_Patch,'rectangle',cell2mat(boundingbox),1);
                    %             fprintf('%d\n',[Startrow, Startcol])
                    IMGdata{h}=I;
                    h=h+1;
                end
            else
                for sdrow=1:ceil(size(img,1)/ stride)
                    if (sdrow-1)*stride+IMGsize<=size(img,1)
                        Startrow=(sdrow-1)*stride+1;
                    else
                        Startrow=size(img,1)-IMGsize+1;
                    end
                    for sdcol=1:ceil(size(img,2)/ stride)
                        if (sdcol-1)*stride+IMGsize<=size(img,2)
                            Startcol=(sdcol-1)*stride+1;
                        else
                            Startcol=size(img,2)-IMGsize+1;
                        end
                        img_Patch=img(Startrow:Startrow+IMGsize-1,Startcol:Startcol+IMGsize-1);
                        mask_Patch=mask(Startrow:Startrow+IMGsize-1,Startcol:Startcol+IMGsize-1);
        
                        if sum(mask_Patch(:))>50000
                            [mask_row,mask_col]=find(mask_Patch>0);
                            boundingbox{1}=min(mask_col);
                            boundingbox{2}=min(mask_row);
                            boundingbox{3}=max(mask_col)-min(mask_col)+1;
                            boundingbox{4}=max(mask_row)-min(mask_row)+1;
        
                            if size(find(img_Patch>0),1)>IMGsize*IMGsize*0.6
                                save([save_path file_name(1:end-4) '_' num2str(h) '.mat'],'img_Patch','boundingbox');
                                
                                I = insertObjectAnnotation(img_Patch,'rectangle',cell2mat(boundingbox),1);
                                %             fprintf('%d\n',[Startrow, Startcol])
                                IMGdata{h}=I;
                                h=h+1;
                            end
                        end
                    end
                end
            end
        %     figure('name', file(i).name ),montage(IMGdata)
        end
        end

        % Button pushed function: RunAnalyzationButton
        function RunAnalyzationButtonPushed(app, event)
        cd('/Users/irischen/Desktop/app/Model/')
        load yolov3Detector_epoch_10_BatchSize_8.mat
        
        data_path='/Users/irischen/Desktop/app/Patch/';
        [pathname,dirname] = uigetfile(data_path);
        load([dirname pathname ]);
        
        imageFiles={};
        boundbox_table={};
        boundbox_table={cell2mat(boundingbox)};
        tmp=double(img_Patch);
        tmp=(tmp-min(tmp(:)))/(max(tmp(:))-min(tmp(:)));
        data=zeros([size(tmp,1),size(tmp,2),3]);
        data(:,:,1)=tmp;
        data(:,:,2)=tmp;
        data(:,:,3)=tmp;
        I = data;
        [bboxes,scores,labels] = detect(yolov3Detector,I,'Threshold', 0.45);
        if isempty(bboxes)
        annotationGT = string(1);
        H = insertObjectAnnotation(data,'rectangle',cell2mat(boundingbox),annotationGT,'LineWidth',3);
        figure, imshow(H)
        else ~isempty(bboxes)
            [selectedBbox,selectedScore,selectedIabel]=selectStrongestBboxMulticlass(bboxes, scores,labels,...
                'RatioType', 'min', 'OverlapThreshold', 0.75);
            max_score_index=find(selectedScore==max(selectedScore));
        
            OLBB={};annotationsOL=[];
            OLBB(1,1)={cell2mat(boundingbox)};
            OLBB(2,1)={double(selectedBbox(max_score_index,:))};
            GTIMG=zeros(size(data,1),size(data,1)); EstIMG=zeros(size(data,1),size(data,1));
            GTIMG((OLBB{1,1}(2):OLBB{1,1}(2)+OLBB{1,1}(4)-1),OLBB{1,1}(1):OLBB{1,1}(1)+OLBB{1,1}(3)-1)=1;
            EstIMG((OLBB{2,1}(2):OLBB{2,1}(2)+OLBB{2,1}(4)-1),OLBB{2,1}(1):OLBB{2,1}(1)+OLBB{2,1}(3)-1)=1;
            Intersect=GTIMG.*EstIMG;
            % figure, imshow(Intersect)
            IoU=sum(Intersect(:))/sum(GTIMG(:)+EstIMG(:)-Intersect(:));
            sepind=findstr('_',pathname);
            GTLabel=pathname(sepind(4)+1:sepind(5)-1);
            figure('color','w')
        
        
            colorarray=['yellow'];
            for i=1:length(selectedScore)
                if selectedScore(i)>=0.75
                    colorarray=[colorarray {'red'}];
                elseif selectedScore(i)>=0.6&selectedScore(i)<0.75
                    colorarray=[colorarray {'green'}];
                elseif selectedScore(i)>=0.45&selectedScore(i)<0.6
                    colorarray=[colorarray {'cyan'}];
                end
            end
        
            selectedScore_IP=[];annotations={};
            tmp=find(selectedScore>0.45);
            selectedScore_IP=[selectedScore_IP selectedScore(tmp) ];
            for i=1:length(tmp)
                annotations{1}=string(['Ground Truth: ' GTLabel]) + " | Confidence: " + num2str(1);
                annotations{i+1} = string( selectedIabel(tmp(i))) + ": " + string(round(selectedScore_IP(i),2));
            end
        
            if isempty(selectedScore_IP)
                imshow(I(:,:,1),[])
            else
                J= insertObjectAnnotation(I,'rectangle',[cell2mat(boundingbox) ;selectedBbox(tmp,:)],cellstr(annotations),'color',colorarray,'LineWidth',3,'FontSize',24);
                imshow(J)
            end
            title([ 'IoU= ' num2str(IoU)],'color','k','FontSize',18)
        end
        return
        annotations = string(labels) + ": " + string(scores);
        K = insertObjectAnnotation(I,'rectangle',bboxes,annotations,'LineWidth',3,'FontSize',24);
        figure, imshow(K)
        
        annotationGT = string(1);
        H = insertObjectAnnotation(data,'rectangle',cell2mat(boundingbox),annotationGT,'LineWidth',3);
        annotationsMax = string(selectedIabel(max_score_index,1)) + ": " + string(selectedScore(max_score_index));
        K = insertObjectAnnotation(I,'rectangle',selectedBbox(max_score_index,:),annotationsMax,'LineWidth',3);
        annotationsOL{1,1} =string(1);
        annotationsOL{2,1} = string(selectedIabel(max_score_index,1)) + ": " + string(selectedScore(max_score_index));
        annotationsOL=string(annotationsOL);
        L = insertObjectAnnotation(data,'rectangle',cell2mat(OLBB),annotationsOL,'color',{'red','yellow'},'LineWidth',3);
        title([ 'IoU= ' num2str(IoU)],'color','w')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.RunAnalyzationButton = uibutton(app.ProcessTab, 'push');
            app.RunAnalyzationButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalyzationButtonPushed, true);
            app.RunAnalyzationButton.Text = 'Run Analyzation';
            app.RunInitializationButton = uibutton(app.ProcessTab, 'push');
            app.RunInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @RunInitializationButtonPushed, true);
            app.RunInitializationButton.Text = 'Run Initialization';
            app.SelectFiletoUploadButton = uibutton(app.ProcessTab, 'push');
            app.SelectFiletoUploadButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiletoUploadButtonPushed, true);
            app.SelectFiletoUploadButton.Text = 'Select File to Upload';
            app.BatchSpinner_2 = uispinner(app.ProcessTab);
            app.BatchSpinner_2Label = uilabel(app.ProcessTab);
            app.BatchSpinner_2Label.Text = 'Batch';
            app.EpochSpinner_2 = uispinner(app.ProcessTab);
            app.EpochSpinner_2Label = uilabel(app.ProcessTab);
            app.EpochSpinner_2Label.Text = 'Epoch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end