classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TabGroup      matlab.ui.container.TabGroup
        UploadTab     matlab.ui.container.Tab
        Image         matlab.ui.control.Image
        UploadButton  matlab.ui.control.Button
        ProcessTab    matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.*', 'Please select a file to import');
            img=dicomread([filepath filename]);
            img=squeeze(img);
            info=dicominfo([filepath filename]);

            img3=double(montage(img,'DisplayRange',[230 750]));
            msgbox('Upload is done!');
            app.UIFigure=img3;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            figure,imshow(img3,[]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UploadTab = uitab(app.TabGroup);
            app.UploadTab.Title = 'Upload';
            app.UploadButton = uibutton(app.UploadTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.Image = uiimage(app.UploadTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end