classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ProcessTab                  matlab.ui.container.Tab
        StepsButtonGroup_2          matlab.ui.container.ButtonGroup
        RunAnalyzationButton_3      matlab.ui.control.Button
        RunInitializationButton_3   matlab.ui.control.Button
        SelectFiletoUploadButton_4  matlab.ui.control.Button
        EpochSpinner_2Label         matlab.ui.control.Label
        BatchSpinner_2Label         matlab.ui.control.Label
        BatchSpinner_2              matlab.ui.control.Spinner
        EpochSpinner_2              matlab.ui.control.Spinner
        RunDisplayButton_3          matlab.ui.control.ToggleButton
        ConfirmresultButton_3       matlab.ui.control.ToggleButton
        ProcessListBox              matlab.ui.control.ListBox
        ProcessListBoxLabel         matlab.ui.control.Label
        ReportTab                   matlab.ui.container.Tab
        PatientInfoPanel            matlab.ui.container.Panel
        SaveButton                  matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        LabledImagePanel            matlab.ui.container.Panel
        NextButton                  matlab.ui.control.Button
        PreviousButton              matlab.ui.control.Button
        Image2_2                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SelectFiletoUploadButtonPushed(app, event)
          
        end

        % Value changed function: ProcessListBox
        function ProcessListBoxValueChanged(app, event)
          
        end

        % Button pushed function: SelectFiletoUploadButton_4
        function SelectFiletoUploadButton_4Pushed(app, event)
            [filename,filepath] = uigetfile('*.*', 'Please select a file to import');

            img=dicomread([filepath filename]);
            img=squeeze(img);
            info=dicominfo([filepath filename]);

            img3=montage(img,'DisplayRange',[230 750],'size',[5 nan]);
            %figure('name',info.PatientID),montage(img,'DisplayRange',[230 750],'size',[5 nan]);

        end

        % Button pushed function: RunInitializationButton_3
        function RunInitializationButton_3Pushed(app, event)
            [filename,filepath] = uigetfile('*.*', 'Please select a file to import');

            img=dicomread([filepath filename]);
            img=squeeze(img);
            info=dicominfo([filepath filename]); 
            
            MIPimg=mean(img,3);  % c-view: summation
            %MIPimg=max(img,[],3);  % c-view
            [row,col]=find(MIPimg~=0);
            centerC=mean(col);
                
                if centerC<round(col/2)
                    laterality_check=1;  % 0 for left, 1 for right
                    ImgLaterality='R';
                    ImgLaterality_r='L';
                    figure('name',[info.PatientID,' Mean image']), imshow(MIPimg,[230 750]), text(50,80,[ImgLaterality info.ViewPosition],'fontsize',30,'fontweight','bold','color','w')
                else
                    laterality_check=0;  % 0 for left, 1 for right
                    ImgLaterality='L';
                    ImgLaterality_r='R';
                    figure('name',[info.PatientID,' Mean image']), imshow(MIPimg,[230 750]), text(size(MIPimg,2)-50,80,[ImgLaterality info.ViewPosition],'HorizontalAlignment','right','fontsize',30,'fontweight','bold','color','w')
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.ProcessListBoxLabel = uilabel(app.ProcessTab);
            app.ProcessListBoxLabel.Text = 'Process';
            app.ProcessListBox = uilistbox(app.ProcessTab);
            app.ProcessListBox.Items = {'Initialization', 'Analyzation', 'Display', 'Results'};
            app.ProcessListBox.ValueChangedFcn = createCallbackFcn(app, @ProcessListBoxValueChanged, true);
            app.ProcessListBox.Value = 'Initialization';
            app.StepsButtonGroup_2 = uibuttongroup(app.ProcessTab);
            app.StepsButtonGroup_2.Title = 'Steps';
            app.ConfirmresultButton_3 = uitogglebutton(app.StepsButtonGroup_2);
            app.ConfirmresultButton_3.Text = 'Confirm result';
            app.RunDisplayButton_3 = uitogglebutton(app.StepsButtonGroup_2);
            app.RunDisplayButton_3.Text = 'Run Display';
            app.RunDisplayButton_3.Value = true;
            app.EpochSpinner_2 = uispinner(app.StepsButtonGroup_2);
            app.BatchSpinner_2 = uispinner(app.StepsButtonGroup_2);
            app.BatchSpinner_2Label = uilabel(app.StepsButtonGroup_2);
            app.BatchSpinner_2Label.Text = 'Batch';
            app.EpochSpinner_2Label = uilabel(app.StepsButtonGroup_2);
            app.EpochSpinner_2Label.Text = 'Epoch';
            app.SelectFiletoUploadButton_4 = uibutton(app.StepsButtonGroup_2, 'push');
            app.SelectFiletoUploadButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectFiletoUploadButton_4Pushed, true);
            app.SelectFiletoUploadButton_4.Text = 'Select File to Upload';
            app.RunInitializationButton_3 = uibutton(app.StepsButtonGroup_2, 'push');
            app.RunInitializationButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunInitializationButton_3Pushed, true);
            app.RunInitializationButton_3.Text = 'Run Initialization';
            app.RunAnalyzationButton_3 = uibutton(app.StepsButtonGroup_2, 'push');
            app.RunAnalyzationButton_3.Text = 'Run Analyzation';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.LabledImagePanel = uipanel(app.ReportTab);
            app.LabledImagePanel.Title = 'Labled Image';
            app.Image2_2 = uiimage(app.LabledImagePanel);
            app.PreviousButton = uibutton(app.LabledImagePanel, 'push');
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.LabledImagePanel, 'push');
            app.NextButton.Text = 'Next';
            app.PatientInfoPanel = uipanel(app.ReportTab);
            app.PatientInfoPanel.Title = 'Patient Info';
            app.TextArea = uitextarea(app.PatientInfoPanel);
            app.SaveButton = uibutton(app.PatientInfoPanel, 'push');
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end