classdef midtermtrial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        StartTab                matlab.ui.container.Tab
        UploadButton            matlab.ui.control.StateButton
        CheckInfoPanel          matlab.ui.container.Panel
        EditField               matlab.ui.control.EditField
        EditFieldLabel          matlab.ui.control.Label
        PersonalInfo            matlab.ui.control.Table
        FilepathEditField       matlab.ui.control.EditField
        FilepathEditFieldLabel  matlab.ui.control.Label
        ProcessTab              matlab.ui.container.Tab
        StartAnalyzeButton      matlab.ui.control.StateButton
        UIAxes                  matlab.ui.control.UIAxes
        ReportTab               matlab.ui.container.Tab
        ShareButton             matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        DataPanel               matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: UploadButton
        function UploadButtonValueChanged(app, event)
            [filename,filepath] = uigetfile('*.*','Please select a file to import.');

%             MIPimg=mean(img,3); 
%             
            if filename
                app.FilepathEditField.Value = [filepath, filename];
                %img = fullfile(filepath, filename);
                img=dicomread(filename);
                img=squeeze(img);

                info=dicominfo([filepath,filename]);
                info=struct2table(info,"AsArray",true);
                app.PersonalInfo.Data = rows2vars(info);
                app.PersonalInfo.ColumnName = info.Properties.VariableNames;

                %% determine the laterality of image based on voxel locations
                MIPimg=mean(img,3);  % c-view: summation
                % MIPimg=max(img,[],3);  % c-view
                [row,col]=find(MIPimg~=0);
                centerC=mean(col);
                
                if centerC>round(col/2)
                    laterality_check=1;  % 0 for left, 1 for right
                    ImgLaterality='R';
                    ImgLaterality_r='L';
                    %figure('name',[info.PatientID,' Mean image']), imshow(MIPimg,[230 750]), text(50,80,[ImgLaterality info.ViewPosition],'fontsize',30,'fontweight','bold','color','w')
                else
                    laterality_check=0;  % 0 for left, 1 for right
                    ImgLaterality='L';
                    ImgLaterality_r='R';
                    %figure('name',[info.PatientID,' Mean image']), imshow(MIPimg,[230 750]), text(size(MIPimg,2)-50,80,[ImgLaterality info.ViewPosition],...'HorizontalAlignment','right','fontsize',30,'fontweight','bold','color','w')           
                end
    
                [r,c,s]=size(MIPimg);

                figure,montage(img,'DisplayRange',[230 750],'size',[5 nan],'ThumbnailSize',[round(r/2) round(c/2)]);
                
                app.EditField.Value = 'name',info.PatientID;

                msgbox('Data import is done! Please check your Info again');

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.CheckInfoPanel = uipanel(app.StartTab);
            app.CheckInfoPanel.Title = 'Check Info';
            app.FilepathEditFieldLabel = uilabel(app.CheckInfoPanel);
            app.FilepathEditFieldLabel.Text = 'FilepathEditField';
            app.FilepathEditField = uieditfield(app.CheckInfoPanel, 'text');
            app.PersonalInfo = uitable(app.CheckInfoPanel);
            app.PersonalInfo.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PersonalInfo.RowName = {};
            app.EditFieldLabel = uilabel(app.CheckInfoPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.CheckInfoPanel, 'text');
            app.UploadButton = uibutton(app.StartTab, 'state');
            app.UploadButton.ValueChangedFcn = createCallbackFcn(app, @UploadButtonValueChanged, true);
            app.UploadButton.Text = 'Upload';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.UIAxes = uiaxes(app.ProcessTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartAnalyzeButton = uibutton(app.ProcessTab, 'state');
            app.StartAnalyzeButton.Text = 'Start Analyze';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.DataPanel = uipanel(app.ReportTab);
            app.DataPanel.Title = 'Data';
            app.Image = uiimage(app.ReportTab);
            app.SaveButton = uibutton(app.ReportTab, 'push');
            app.SaveButton.Text = 'Save';
            app.ShareButton = uibutton(app.ReportTab, 'push');
            app.ShareButton.Text = 'Share';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = midtermtrial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end